classdef Mav_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartoptimizationButton     matlab.ui.control.Button
        anglesthatthearmformintheverticalplaneCheckBox  matlab.ui.control.CheckBox
        anglesthatthearmforminthehorizontalplaneCheckBox  matlab.ui.control.CheckBox
        LarmlengthCheckBox          matlab.ui.control.CheckBox
        numberofrotorsCheckBox      matlab.ui.control.CheckBox
        ParameterstooptimizeLabel   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        NumberofrotorsSpinnerLabel  matlab.ui.control.Label
        NumberofrotorsSpinner       matlab.ui.control.Spinner
        SpinnerLabel                matlab.ui.control.Label
        Spinner                     matlab.ui.control.Spinner
        Label                       matlab.ui.control.Label
        Spinner_2                   matlab.ui.control.Spinner
        Label_2                     matlab.ui.control.Label
        Spinner_3                   matlab.ui.control.Spinner
        Label_3                     matlab.ui.control.Label
        Spinner_4                   matlab.ui.control.Spinner
        Label_4                     matlab.ui.control.Label
        Spinner_5                   matlab.ui.control.Spinner
        Label_5                     matlab.ui.control.Label
        Spinner_6                   matlab.ui.control.Spinner
        Label_6                     matlab.ui.control.Label
        Spinner_7                   matlab.ui.control.Spinner
        Label_7                     matlab.ui.control.Label
        Spinner_8                   matlab.ui.control.Spinner
        Label_8                     matlab.ui.control.Label
        Spinner_9                   matlab.ui.control.Spinner
        Label_9                     matlab.ui.control.Label
        Spinner_10                  matlab.ui.control.Spinner
        Label_10                    matlab.ui.control.Label
        Spinner_11                  matlab.ui.control.Spinner
        Label_11                    matlab.ui.control.Label
        Spinner_12                  matlab.ui.control.Spinner
        Label_12                    matlab.ui.control.Label
        Spinner_13                  matlab.ui.control.Spinner
        Label_13                    matlab.ui.control.Label
        Spinner_14                  matlab.ui.control.Spinner
        Label_14                    matlab.ui.control.Label
        Spinner_15                  matlab.ui.control.Spinner
        Label_15                    matlab.ui.control.Label
        Spinner_16                  matlab.ui.control.Spinner
        L0SpinnerLabel              matlab.ui.control.Label
        L0Spinner                   matlab.ui.control.Spinner
        InitialsolutionLabel        matlab.ui.control.Label
        AdvancedparametersoffmincomLabel  matlab.ui.control.Label
        TooptimizethenumberofrotorstheoptimisationtoolLabel  matlab.ui.control.Label
        Spinner_17                  matlab.ui.control.Spinner
        Spinner_18                  matlab.ui.control.Spinner
        MinimumnumberofrotorsLabel  matlab.ui.control.Label
        MaximumnumberofrotorsLabel  matlab.ui.control.Label
        AlgorithmListBoxLabel       matlab.ui.control.Label
        AlgorithmListBox            matlab.ui.control.ListBox
        DisplayLabel                matlab.ui.control.Label
        DisplayListBox              matlab.ui.control.ListBox
        MaximumiterationsofthealgorithmLabel  matlab.ui.control.Label
        MaximumiterationsofthealgorithmSpinner  matlab.ui.control.Spinner
        ToleranceontheconstraintviolationSpinnerLabel  matlab.ui.control.Label
        ToleranceontheconstraintviolationSpinner  matlab.ui.control.Spinner
        TerminationtoleranceontheoptargSpinnerLabel  matlab.ui.control.Label
        TerminationtoleranceontheoptargSpinner  matlab.ui.control.Spinner
        ParametersoftheoptimizationfunctionLabel_2  matlab.ui.control.Label
        CheckBox                    matlab.ui.control.CheckBox
        OptimizationtooltofindanoptimaldronedesignLabel  matlab.ui.control.Label
        CostfunctionLabel           matlab.ui.control.Label
        CostfunctionListBox         matlab.ui.control.ListBox
        LminSpinnerLabel            matlab.ui.control.Label
        LminSpinner                 matlab.ui.control.Spinner
        LmaxSpinnerLabel            matlab.ui.control.Label
        LmaxSpinner                 matlab.ui.control.Spinner
        minSpinnerLabel             matlab.ui.control.Label
        minSpinner                  matlab.ui.control.Spinner
        maxSpinnerLabel             matlab.ui.control.Label
        maxSpinner                  matlab.ui.control.Spinner
        minLabel                    matlab.ui.control.Label
        minSpinner_4                matlab.ui.control.Spinner
        maxSpinner_4Label           matlab.ui.control.Label
        maxSpinner_4                matlab.ui.control.Spinner
        ParametersboundsLabel       matlab.ui.control.Label
        LSpinnerLabel               matlab.ui.control.Label
        LSpinner                    matlab.ui.control.Spinner
        DesignparametersLabel       matlab.ui.control.Label
        DirectiondLabel             matlab.ui.control.Label
        xSpinnerLabel               matlab.ui.control.Label
        xSpinner                    matlab.ui.control.Spinner
        ySpinnerLabel               matlab.ui.control.Label
        ySpinner                    matlab.ui.control.Spinner
        zSpinnerLabel               matlab.ui.control.Label
        zSpinner                    matlab.ui.control.Spinner
        MaximalnumberoftimesfmincomisiteratedSpinnerLabel_2  matlab.ui.control.Label
        MaximalnumberoftimesfmincomisiteratedSpinner_2  matlab.ui.control.Spinner
        NumberofpointsofthetorqueandforcespaceDropDownLabel  matlab.ui.control.Label
        NumberofpointsofthetorqueandforcespaceDropDown  matlab.ui.control.DropDown
        PerformanoptimizationonthetiltinganglesandontheLabel  matlab.ui.control.Label
        DesignnumberEditFieldLabel  matlab.ui.control.Label
        DesignnumberEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Property % Description
        beta1 = 0
        beta2 = 0
        beta3 = 0
        beta4 = 0
        beta5 = 0
        beta6 = 0
        beta7 = 0
        beta8 = 0
        theta1 = 0
        theta2 = 0
        theta3 = 0
        theta4 = 0
        theta5 = 0
        theta6 = 0
        theta7 = 0
        theta8 = 0
        Optimize_theta = false
        Optimize_L = false
        Optimize_n = false
        beta_ub = 90
        beta_lb = -90
        theta_ub = 90
        theta_lb = -90
        L_ub = 0.5
        L_lb = 0.1
        n_ub = 8
        n_lb = 3
        n = 3
        L = 0.3
        L0 = 0.3
        direction = [0, 0, 1].';
        cost_fct_case = 1;
        step = .1; 
        design_number = 1
        max_iterations = 150;
        optimize_alpha = false;
        Algorithm = 'sqp';
        Display = 'off';
        maxIter = 10000;
        StepTolerance = 1.0000e-6;
        ConstraintTolerance = 1.0000e-6
    end


    methods (Access = private)

        % Button pushed function: StartoptimizationButton
        function StartoptimizationButtonPushed(app, event)
            app.StartoptimizationButton.Enable = 'off';
            if app.Optimize_L
                Linit = app.L0;
            else
                Linit = app.L;
            end
            beta = [app.beta1, app.beta2, app.beta3, app.beta4, app.beta5, app.beta6, app.beta7, app.beta8];
            for i = 1:app.n
                beta0(i) = beta(i);
            end
            theta = [app.theta1, app.theta2, app.theta3, app.theta4, app.theta5, app.theta6, app.theta7, app.theta8];
            if app.Optimize_theta
                for i = 1:app.n
                    theta0(i) = theta(i);
                end
            else
                theta0 = zeros(1,app.n); 
            end
            beta0 = deg2rad(beta0);
            theta0 = deg2rad(theta0);
            app.beta_lb = deg2rad(app.beta_lb);
            app.beta_ub = deg2rad(app.beta_ub);
            app.theta_lb = deg2rad(app.theta_lb);
            app.theta_ub = deg2rad(app.theta_ub);
            Mav_GUI_link(app.cost_fct_case, app.Optimize_theta, app.Optimize_L, app.Optimize_n, app.direction, app.design_number, app.n_lb, app.n_ub, app.L_lb, app.L_ub, app.beta_lb, app.beta_ub, app.theta_lb, app.theta_ub, app.n, Linit, theta0, beta0, app.max_iterations, app.step, app.optimize_alpha, app.Display, app.Algorithm, app.maxIter, app.StepTolerance, app.ConstraintTolerance);      
        end

        % Value changed function: 
        % anglesthatthearmforminthehorizontalplaneCheckBox
        function anglesthatthearmforminthehorizontalplaneCheckBoxValueChanged(app, event)
            value = app.anglesthatthearmforminthehorizontalplaneCheckBox.Value;
            if value == true
                app.Optimize_theta = true;
                app.Lamp.Color = 'green';
                app.Spinner_9.Enable = 'on';
                app.Label_8.Enable = 'on';
                app.Spinner_10.Enable = 'on';
                app.Label_9.Enable = 'on';
                app.Spinner_11.Enable = 'on';
                app.Label_10.Enable = 'on';
                app.minSpinner_4.Enable = 'on';
                app.minLabel.Enable = 'on';
                app.maxSpinner_4.Enable = 'on';
                app.maxSpinner_4Label.Enable = 'on';
                if app.n == 4
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                elseif app.n == 5
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                elseif app.n == 6
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                elseif app.n == 7
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Label_14.Enable = 'on';
                elseif app.n == 8
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Label_14.Enable = 'on';
                    app.Spinner_16.Enable = 'on';
                    app.Label_15.Enable = 'on';
                end
            else
                app.Optimize_theta = false;
                app.Lamp.Color = 'red';
                app.Spinner_9.Enable = 'off';
                app.Label_8.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Label_9.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Label_10.Enable = 'off';
                app.minSpinner_4.Enable = 'off';
                app.minLabel.Enable = 'off';
                app.maxSpinner_4.Enable = 'off';
                app.maxSpinner_4Label.Enable = 'off';
                if app.n == 4
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                elseif app.n == 5
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                elseif app.n == 6
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Label_13.Enable = 'off';
                elseif app.n == 7
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Label_13.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Label_14.Enable = 'off';
                elseif app.n == 8
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Label_13.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Label_14.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                    app.Label_15.Enable = 'off';
                end
            end
            if app.Optimize_n == true
                app.Spinner_9.Enable = 'off';
                app.Label_8.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Label_9.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Label_10.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Label_11.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Label_12.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Label_13.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Label_14.Enable = 'off';
                app.Spinner_16.Enable = 'off';
                app.Label_15.Enable = 'off';
            end
        end

        % Value changed function: LarmlengthCheckBox
        function LarmlengthCheckBoxValueChanged(app, event)
            value = app.LarmlengthCheckBox.Value;
            if value == true
                app.Optimize_L = true;
                app.Lamp2.Color = 'green';
                app.L0Spinner.Enable = 'on';
                app.L0SpinnerLabel.Enable = 'on';
                app.LminSpinner.Enable = 'on';
                app.LminSpinnerLabel.Enable = 'on';
                app.LmaxSpinner.Enable = 'on';
                app.LmaxSpinnerLabel.Enable = 'on';
                app.LSpinner.Enable = 'off';
                app.LSpinnerLabel.Enable = 'off';
            else
                app.Optimize_L = false;
                app.Lamp2.Color = 'red';
                app.L0Spinner.Enable = 'off';
                app.L0SpinnerLabel.Enable = 'off';
                app.LminSpinner.Enable = 'off';
                app.LminSpinnerLabel.Enable = 'off';
                app.LmaxSpinner.Enable = 'off';
                app.LmaxSpinnerLabel.Enable = 'off';
                app.LSpinner.Enable = 'on';
                app.LSpinnerLabel.Enable = 'on';
            end
            if app.Optimize_n == true
                app.L0Spinner.Enable = 'off';
                app.L0SpinnerLabel.Enable = 'off';
            end
                
        end

        % Value changed function: numberofrotorsCheckBox
        function numberofrotorsCheckBoxValueChanged(app, event)
            value = app.numberofrotorsCheckBox.Value;
            if value == true
                % Change the dropdownmenu of cost fcts
                app.CostfunctionListBox.ItemsData = ['1'; '2'; '3'];
                app.CostfunctionListBox.Items{1} = 'Maximize the minimal torque and force that the drone can apply and minimize the inertia of the drone';
                app.CostfunctionListBox.Items{2} = 'Maximize the torque and force that the drone can apply in x, y and z directions and minimize the inertia of the drone';
                app.CostfunctionListBox.Items{3} = 'Maximize the force and torque that the drone can apply in one direction (d) and minimize the inertia of the drone';
                                                 
                % Change differrent access to different features of the tool
                app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'on';
                app.Spinner_17.Enable = 'on';
                app.MinimumnumberofrotorsLabel.Enable = 'on';
                app.Spinner_18.Enable = 'on';
                app.MaximumnumberofrotorsLabel.Enable = 'on';
                app.Optimize_n = true;
                app.Lamp3.Color = 'green';
                app.NumberofrotorsSpinner.Enable = 'off';
                app.NumberofrotorsSpinnerLabel.Enable = 'off';
                app.L0Spinner.Enable = 'off';
                app.L0SpinnerLabel.Enable = 'off';
                app.Spinner.Enable = 'off';
                app.SpinnerLabel.Enable = 'off';
                app.Spinner_2.Enable = 'off';
                app.Label.Enable = 'off';
                app.Spinner_3.Enable = 'off';
                app.Label_2.Enable = 'off';
                app.Spinner_4.Enable = 'off';
                app.Label_3.Enable = 'off';
                app.Spinner_5.Enable = 'off';
                app.Label_4.Enable = 'off';
                app.Spinner_6.Enable = 'off';
                app.Label_5.Enable = 'off';
                app.Spinner_7.Enable = 'off';
                app.Label_6.Enable = 'off';
                app.Spinner_8.Enable = 'off';
                app.Label_7.Enable = 'off';
                app.Spinner_9.Enable = 'off';
                app.Label_8.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Label_9.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Label_10.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Label_11.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Label_12.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Label_13.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Label_14.Enable = 'off';
                app.Spinner_16.Enable = 'off';
                app.Label_15.Enable = 'off';
                app.AlgorithmListBox.Enable = 'off';
                app.AlgorithmListBoxLabel.Enable = 'off';
                app.DisplayListBox.Enable = 'off';
                app.DisplayLabel.Enable = 'off';
                app.MaximumiterationsofthealgorithmSpinner.Enable = 'off';
                app.MaximumiterationsofthealgorithmLabel.Enable = 'off';
                app.ToleranceontheconstraintviolationSpinner.Enable = 'off';
                app.ToleranceontheconstraintviolationSpinnerLabel.Enable = 'off';
                app.TerminationtoleranceontheoptargSpinner.Enable = 'off';
                app.TerminationtoleranceontheoptargSpinnerLabel.Enable = 'off';
                app.MaximalnumberoftimesfmincomisiteratedSpinner_2.Enable = 'off';
                app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel_2.Enable = 'off';
                if app.Optimize_L == true
                    app.LminSpinner.Enable = 'on';
                    app.LminSpinnerLabel.Enable = 'on';
                    app.LmaxSpinner.Enable = 'on';
                    app.LmaxSpinnerLabel.Enable = 'on';
                    app.LSpinner.Enable = 'off';
                    app.LSpinnerLabel.Enable = 'off';
                else
                    app.LminSpinner.Enable = 'off';
                    app.LminSpinnerLabel.Enable = 'off';
                    app.LmaxSpinner.Enable = 'off';
                    app.LmaxSpinnerLabel.Enable = 'off';
                    app.LSpinner.Enable = 'on';
                    app.LSpinnerLabel.Enable = 'on';
                end
                if app.Optimize_theta == true
                    app.minSpinner_4.Enable = 'on';
                    app.minLabel.Enable = 'on';
                    app.maxSpinner_4.Enable = 'on';
                    app.maxSpinner_4Label.Enable = 'on';
                else
                    app.minSpinner_4.Enable = 'off';
                    app.minLabel.Enable = 'off';
                    app.maxSpinner_4.Enable = 'off';
                    app.maxSpinner_4Label.Enable = 'off';
                end
            else
                % Change the dropdownmenu of cost fcts
                app.CostfunctionListBox.Items = {'Maximize the minimal torque and force that the drone can apply'; ...
                                                 'Maximize the minimal torque and force that the drone can apply and minimize the inertia of the drone'; ...
                                                 'Maximize the force and torque in every direction and the hover efficiency in any orientation'; ...
                                                 'Maximize the force and torque that the drone can apply in one direction (d)'; ...
                                                 'Maximize the force and torque that the drone can apply in one direction (d), with a hovering in any orientation condition'; ...
                                                 'Maximize the torque and force that the drone can apply in x, y and z directions'; ...
                                                 'Maximize the volume of the force space and the torque space'};
                app.CostfunctionListBox.ItemsData = ['1';'2';'3';'4';'5';'6';'7'];
                % Change differrent access to different features of the tool
                app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'off';
                app.Spinner_17.Enable = 'off';
                app.Spinner_18.Enable = 'off';
                app.MinimumnumberofrotorsLabel.Enable = 'off';
                app.MaximumnumberofrotorsLabel.Enable = 'off';
                app.AlgorithmListBox.Enable = 'on';
                app.AlgorithmListBoxLabel.Enable = 'on';
                app.DisplayListBox.Enable = 'on';
                app.DisplayLabel.Enable = 'on';
                app.MaximumiterationsofthealgorithmSpinner.Enable = 'on';
                app.MaximumiterationsofthealgorithmLabel.Enable = 'on';
                app.ToleranceontheconstraintviolationSpinner.Enable = 'on';
                app.ToleranceontheconstraintviolationSpinnerLabel.Enable = 'on';
                app.TerminationtoleranceontheoptargSpinner.Enable = 'on';
                app.TerminationtoleranceontheoptargSpinnerLabel.Enable = 'on';
                app.MaximalnumberoftimesfmincomisiteratedSpinner_2.Enable = 'on';
                app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel_2.Enable = 'on';
                app.Optimize_n = false;
                if app.Optimize_L == true
                    app.L0Spinner.Enable = 'on';
                    app.L0SpinnerLabel.Enable = 'on';
                    app.LminSpinner.Enable = 'on';
                    app.LminSpinnerLabel.Enable = 'on'; 
                    app.LmaxSpinner.Enable = 'on';
                    app.LmaxSpinnerLabel.Enable = 'on';
                    app.LSpinner.Enable = 'off';
                    app.LSpinnerLabel.Enable = 'off';
                else
                    app.L0Spinner.Enable = 'off';
                    app.L0SpinnerLabel.Enable = 'off';
                    app.LminSpinner.Enable = 'off';
                    app.LminSpinnerLabel.Enable = 'off'; 
                    app.LmaxSpinner.Enable = 'off';
                    app.LmaxSpinnerLabel.Enable = 'off';
                    app.LSpinner.Enable = 'on';
                    app.LSpinnerLabel.Enable = 'on';
                end
                app.Lamp3.Color = 'red';
                app.NumberofrotorsSpinner.Enable = 'on';
                app.NumberofrotorsSpinnerLabel.Enable = 'on';
                app.Spinner.Enable = 'on';
                app.SpinnerLabel.Enable = 'on';
                app.Spinner_2.Enable = 'on';
                app.Label.Enable = 'on';
                app.Spinner_3.Enable = 'on';
                app.Label_2.Enable = 'on';
                app.Spinner_4.Enable = 'off';
                app.Label_3.Enable = 'off';
                app.Spinner_5.Enable = 'off';
                app.Label_4.Enable = 'off';
                app.Spinner_6.Enable = 'off';
                app.Label_5.Enable = 'off';
                app.Spinner_7.Enable = 'off';
                app.Label_6.Enable = 'off';
                app.Spinner_8.Enable = 'off';
                app.Label_7.Enable = 'off';
                if app.Optimize_theta == true
                    app.minSpinner_4.Enable = 'on';
                    app.minLabel.Enable = 'on';
                    app.maxSpinner_4.Enable = 'on';
                    app.maxSpinner_4Label.Enable = 'on';
                    app.Spinner_9.Enable = 'on';
                    app.Label_8.Enable = 'on';
                    app.Spinner_10.Enable = 'on';
                    app.Label_9.Enable = 'on';
                    app.Spinner_11.Enable = 'on';
                    app.Label_10.Enable = 'on';
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Label_13.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Label_14.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                    app.Label_15.Enable = 'off';
                else
                    app.minSpinner_4.Enable = 'off';
                    app.minLabel.Enable = 'off';
                    app.maxSpinner_4.Enable = 'off';
                    app.maxSpinner_4Label.Enable = 'off';
                    app.Spinner_9.Enable = 'off';
                    app.Label_8.Enable = 'off';
                    app.Spinner_10.Enable = 'off';
                    app.Label_9.Enable = 'off';
                    app.Spinner_11.Enable = 'off';
                    app.Label_10.Enable = 'off';
                    app.Spinner_12.Enable = 'off';
                    app.Label_11.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Label_12.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Label_13.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Label_14.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                    app.Label_15.Enable = 'off';
                end
                if app.n ==4
                    app.Spinner_4.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Label_11.Enable = 'on';
                    end
                elseif app.n ==5
                    app.Spinner_4.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Label_4.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Label_11.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Label_12.Enable = 'on';
                    end
                elseif app.n ==6
                    app.Spinner_4.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Label_4.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    app.Label_5.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Label_11.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Label_12.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                        app.Label_13.Enable = 'on';
                    end
                elseif app.n==7
                    app.Spinner_4.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Label_4.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    app.Label_5.Enable = 'on';
                    app.Spinner_7.Enable = 'on';
                    app.Label_6.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Label_11.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Label_12.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                        app.Label_13.Enable = 'on';
                        app.Spinner_15.Enable = 'on';
                        app.Label_14.Enable = 'on';
                        
                    end
                elseif app.n==8
                    app.Spinner_4.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Label_4.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    app.Label_5.Enable = 'on';
                    app.Spinner_7.Enable = 'on';
                    app.Label_6.Enable = 'on';
                    app.Spinner_8.Enable = 'on';
                    app.Label_7.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Label_11.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Label_12.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                        app.Label_13.Enable = 'on';
                        app.Spinner_15.Enable = 'on';
                        app.Label_14.Enable = 'on';
                        app.Spinner_16.Enable = 'on';
                        app.Label_15.Enable = 'on';
                    end
                end
            end
        end

        % Value changed function: NumberofrotorsSpinner
        function NumberofrotorsSpinnerValueChanged(app, event)
            value = app.NumberofrotorsSpinner.Value;
            app.n = value;
            app.Spinner.Enable = 'on';
            app.SpinnerLabel.Enable = 'on';
            app.Spinner_2.Enable = 'on';
            app.Label.Enable = 'on';
            app.Spinner_3.Enable = 'on';
            app.Label_2.Enable = 'on';
            app.Spinner_4.Enable = 'off';
            app.Label_3.Enable = 'off';
            app.Spinner_5.Enable = 'off';
            app.Label_4.Enable = 'off';
            app.Spinner_6.Enable = 'off';
            app.Label_5.Enable = 'off';
            app.Spinner_7.Enable = 'off';
            app.Label_6.Enable = 'off';
            app.Spinner_8.Enable = 'off';
            app.Label_7.Enable = 'off';
            if app.Optimize_theta == true
                app.Spinner_9.Enable = 'on';
                app.Label_8.Enable = 'on';
                app.Spinner_10.Enable = 'on';
                app.Label_9.Enable = 'on';
                app.Spinner_11.Enable = 'on';
                app.Label_10.Enable = 'on';
                app.Spinner_12.Enable = 'off';
                app.Label_11.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Label_12.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Label_13.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Label_14.Enable = 'off';
                app.Spinner_16.Enable = 'off';
                app.Label_15.Enable = 'off';
            else
                app.Spinner_9.Enable = 'off';
                app.Label_8.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Label_9.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Label_10.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Label_11.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Label_12.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Label_13.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Label_14.Enable = 'off';
                app.Spinner_16.Enable = 'off';
                app.Label_15.Enable = 'off';
            end
            if value ==4
                app.Spinner_4.Enable = 'on';
                app.Label_3.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                end
            elseif value==5
                app.Spinner_4.Enable = 'on';
                app.Label_3.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Label_4.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                end
            elseif value==6
                app.Spinner_4.Enable = 'on';
                app.Label_3.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Label_4.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                app.Label_5.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                end
            elseif value==7
                app.Spinner_4.Enable = 'on';
                app.Label_3.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Label_4.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                app.Label_5.Enable = 'on';
                app.Spinner_7.Enable = 'on';
                app.Label_6.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Label_14.Enable = 'on';
                end
            elseif value==8
                app.Spinner_4.Enable = 'on';
                app.Label_3.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Label_4.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                app.Label_5.Enable = 'on';
                app.Spinner_7.Enable = 'on';
                app.Label_6.Enable = 'on';
                app.Spinner_8.Enable = 'on';
                app.Label_7.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Label_11.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Label_12.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Label_13.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Label_14.Enable = 'on';
                    app.Spinner_16.Enable = 'on';
                    app.Label_15.Enable = 'on';
                end
            end
        end

        % Value changed function: Spinner_17
        function Spinner_17ValueChanged(app, event)
            value = app.Spinner_17.Value;
            app.Spinner_18.Limits = [value, 10];
            app.n_lb = value;
        end

        % Value changed function: minSpinner_4
        function minSpinner_4ValueChanged(app, event)
            value = app.minSpinner_4.Value;
            app.theta_lb = value;
            app.Spinner_9.Limits = [value, app.theta_ub];
            app.Spinner_10.Limits = [value, app.theta_ub];
            app.Spinner_11.Limits = [value, app.theta_ub];
            app.Spinner_12.Limits = [value, app.theta_ub];
            app.Spinner_13.Limits = [value, app.theta_ub];
            app.Spinner_14.Limits = [value, app.theta_ub];
            app.Spinner_15.Limits = [value, app.theta_ub];
            app.Spinner_16.Limits = [value, app.theta_ub];
        end

        % Value changed function: maxSpinner_4
        function maxSpinner_4ValueChanged(app, event)
            value = app.maxSpinner_4.Value;
            app.theta_ub = value;
            app.Spinner_9.Limits = [app.theta_lb, value];
            app.Spinner_10.Limits = [app.theta_lb, value];
            app.Spinner_11.Limits = [app.theta_lb, value];
            app.Spinner_12.Limits = [app.theta_lb, value];
            app.Spinner_13.Limits = [app.theta_lb, value];
            app.Spinner_14.Limits = [app.theta_lb, value];
            app.Spinner_15.Limits = [app.theta_lb, value];
            app.Spinner_16.Limits = [app.theta_lb, value];
        end

        % Value changed function: LminSpinner
        function LminSpinnerValueChanged(app, event)
            value = app.LminSpinner.Value;
            app.L_lb = value;
            app.L0Spinner.Limits = [app.L_lb, app.L_ub];
        end

        % Value changed function: LmaxSpinner
        function LmaxSpinnerValueChanged(app, event)
            value = app.LmaxSpinner.Value;
            app.L_ub = value;
            app.L0Spinner.Limits = [app.L_lb, app.L_ub];
        end

        % Value changed function: minSpinner
        function minSpinnerValueChanged(app, event)
            value = app.minSpinner.Value;
            app.beta_lb = value;
            app.Spinner.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_2.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_3.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_4.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_5.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_6.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_7.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_8.Limits = [app.beta_lb, app.beta_ub];
        end

        % Value changed function: maxSpinner
        function maxSpinnerValueChanged(app, event)
            value = app.maxSpinner.Value;
            app.beta_ub = value;
            app.Spinner.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_2.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_3.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_4.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_5.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_6.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_7.Limits = [app.beta_lb, app.beta_ub];
            app.Spinner_8.Limits = [app.beta_lb, app.beta_ub];
        end

        % Value changed function: LSpinner
        function LSpinnerValueChanged(app, event)
            value = app.LSpinner.Value;
            app.L = value;
        end

        % Value changed function: L0Spinner
        function L0SpinnerValueChanged(app, event)
            value = app.L0Spinner.Value;
            app.L0 = value;
        end

        % Value changed function: CostfunctionListBox
        function CostfunctionListBoxValueChanged(app, event)
            value = app.CostfunctionListBox.Value;
            app.cost_fct_case = value;
            if value == '4' || value == '5' || (app.Optimize_n && value == '3')
                app.xSpinner.Enable = 'on';
                app.ySpinner.Enable = 'on';
                app.zSpinner.Enable = 'on';
                app.xSpinnerLabel.Enable = 'on';
                app.ySpinnerLabel.Enable = 'on';
                app.zSpinnerLabel.Enable = 'on';
                app.DirectiondLabel.Enable = 'on';
            else
                app.xSpinner.Enable = 'off';
                app.ySpinner.Enable = 'off';
                app.zSpinner.Enable = 'off';
                app.xSpinnerLabel.Enable = 'off';
                app.ySpinnerLabel.Enable = 'off';
                app.zSpinnerLabel.Enable = 'off';
                app.DirectiondLabel.Enable = 'off';
            end
        end

        % Value changed function: DisplayListBox
        function DisplayListBoxValueChanged(app, event)
            value = app.DisplayListBox.Value;
            app.Display = value;
        end

        % Value changed function: AlgorithmListBox
        function AlgorithmListBoxValueChanged(app, event)
            value = app.AlgorithmListBox.Value;
            app.Algorithm = value;
            
        end

        % Value changed function: 
        % MaximumiterationsofthealgorithmSpinner
        function MaximumiterationsofthealgorithmSpinnerValueChanged(app, event)
            value = app.MaximumiterationsofthealgorithmSpinner.Value;
            app.maxIter = value;
        end

        % Value changed function: 
        % ToleranceontheconstraintviolationSpinner
        function ToleranceontheconstraintviolationSpinnerValueChanged(app, event)
            value = app.ToleranceontheconstraintviolationSpinner.Value;
            app.ConstraintTolerance = value;
        end

        % Value changed function: 
        % TerminationtoleranceontheoptargSpinner
        function TerminationtoleranceontheoptargSpinnerValueChanged(app, event)
            value = app.TerminationtoleranceontheoptargSpinner.Value;
            app.StepTolerance = value;
        end

        % Value changed function: 
        % MaximalnumberoftimesfmincomisiteratedSpinner_2
        function MaximalnumberoftimesfmincomisiteratedSpinner_2ValueChanged(app, event)
            value = app.MaximalnumberoftimesfmincomisiteratedSpinner_2.Value;
            app.max_iterations = value;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            app.optimize_alpha = value;
        end

        % Value changed function: Spinner_18
        function Spinner_18ValueChanged(app, event)
            value = app.Spinner_18.Value;
            app.n_ub = value;
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(app, event)
            value = app.xSpinner.Value;
            if app.direction(2) ==0 && app.direction(3)==0 && value == 0
            app.xSpinner.Value = 1;
            value = 1;
            end
            app.direction(1) = value;
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)
            value = app.ySpinner.Value;
            if app.direction(1) ==0 && app.direction(3)==0 && value == 0
            app.ySpinner.Value = 1;
            value = 1;
            end
            app.direction(2) = value;
        end

        % Value changed function: zSpinner
        function zSpinnerValueChanged(app, event)
            value = app.zSpinner.Value;
            if app.direction(1) ==0 && app.direction(2)==0 && value == 0
            app.zSpinner.Value = 1;
            value = 1;
            end
            app.direction(3) = value;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.beta1 = value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            app.beta2 = value;
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            value = app.Spinner_3.Value;
            app.beta3 = value;
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            value = app.Spinner_4.Value;
            app.beta4 = value;
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            value = app.Spinner_5.Value;
            app.beta5 = value;
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            value = app.Spinner_6.Value;
            app.beta6 = value;
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            value = app.Spinner_7.Value;
            app.beta7 = value;
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            value = app.Spinner_8.Value;
            app.beta8 = value;
        end

        % Value changed function: Spinner_9
        function Spinner_9ValueChanged(app, event)
            value = app.Spinner_9.Value;
            app.theta1 = value;
        end

        % Value changed function: Spinner_10
        function Spinner_10ValueChanged(app, event)
            value = app.Spinner_10.Value;
            app.theta2 = value;
        end

        % Value changed function: Spinner_11
        function Spinner_11ValueChanged(app, event)
            value = app.Spinner_11.Value;
            app.theta3 = value;
        end

        % Value changed function: Spinner_12
        function Spinner_12ValueChanged(app, event)
            value = app.Spinner_12.Value;
            app.theta4 = value;
        end

        % Value changed function: Spinner_13
        function Spinner_13ValueChanged(app, event)
            value = app.Spinner_13.Value;
            app.theta5 = value;
        end

        % Value changed function: Spinner_14
        function Spinner_14ValueChanged(app, event)
            value = app.Spinner_14.Value;
            app.theta6 = value;
        end

        % Value changed function: Spinner_15
        function Spinner_15ValueChanged(app, event)
            value = app.Spinner_15.Value;
            app.theta7 = value;
        end

        % Value changed function: Spinner_16
        function Spinner_16ValueChanged(app, event)
            value = app.Spinner_16.Value;
            app.theta8 = value;
        end

        % Value changed function: 
        % NumberofpointsofthetorqueandforcespaceDropDown
        function NumberofpointsofthetorqueandforcespaceDropDownValueChanged(app, event)
            value = app.NumberofpointsofthetorqueandforcespaceDropDown.Value;
            if value == '0.2'
                app.step = 0.2;
            elseif value == '0.25'
                app.step = 0.25;
            elseif value == '0.5'
                app.step = 0.5;
            elseif value == '1'
                app.step = 1.0;
            else
                app.step = 0.1;
            end
        end

        % Value changed function: DesignnumberEditField
        function DesignnumberEditFieldValueChanged(app, event)
            value = app.DesignnumberEditField.Value;
            app.design_number = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartoptimizationButton = uibutton(app.UIFigure, 'push');
            app.StartoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartoptimizationButtonPushed, true);
            app.StartoptimizationButton.Text = 'Start optimization';
            app.anglesthatthearmformintheverticalplaneCheckBox = uicheckbox(app.UIFigure);
            app.anglesthatthearmformintheverticalplaneCheckBox.Enable = 'off';
            app.anglesthatthearmformintheverticalplaneCheckBox.Text = 'β (angles that the arm form in the vertical plane)';
            app.anglesthatthearmformintheverticalplaneCheckBox.Value = true;
            app.anglesthatthearmforminthehorizontalplaneCheckBox = uicheckbox(app.UIFigure);
            app.anglesthatthearmforminthehorizontalplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @anglesthatthearmforminthehorizontalplaneCheckBoxValueChanged, true);
            app.anglesthatthearmforminthehorizontalplaneCheckBox.Text = 'θ (angles that the arm form in the horizontal plane)';
            app.LarmlengthCheckBox = uicheckbox(app.UIFigure);
            app.LarmlengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @LarmlengthCheckBoxValueChanged, true);
            app.LarmlengthCheckBox.Text = 'L (arm length)';
            app.numberofrotorsCheckBox = uicheckbox(app.UIFigure);
            app.numberofrotorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @numberofrotorsCheckBoxValueChanged, true);
            app.numberofrotorsCheckBox.Text = 'n (umber of rotors)';
            app.ParameterstooptimizeLabel = uilabel(app.UIFigure);
            app.ParameterstooptimizeLabel.Text = 'Parameters to optimize:';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp4 = uilamp(app.UIFigure);
            app.Lamp4.Enable = 'off';
            app.NumberofrotorsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofrotorsSpinnerLabel.Text = 'Number of rotors';
            app.NumberofrotorsSpinner = uispinner(app.UIFigure);
            app.NumberofrotorsSpinner.Limits = [3 8];
            app.NumberofrotorsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofrotorsSpinnerValueChanged, true);
            app.NumberofrotorsSpinner.Value = 3;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'β1:';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'β2:';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'β3:';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = 'β4:';
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_4.Enable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = 'β5:';
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_5.Enable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = 'β6:';
            app.Spinner_6 = uispinner(app.UIFigure);
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_6.Enable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = 'β7:';
            app.Spinner_7 = uispinner(app.UIFigure);
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.Spinner_7.Enable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Enable = 'off';
            app.Label_7.Text = 'β8:';
            app.Spinner_8 = uispinner(app.UIFigure);
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            app.Spinner_8.Enable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Enable = 'off';
            app.Label_8.Text = 'θ1:';
            app.Spinner_9 = uispinner(app.UIFigure);
            app.Spinner_9.ValueChangedFcn = createCallbackFcn(app, @Spinner_9ValueChanged, true);
            app.Spinner_9.Enable = 'off';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Enable = 'off';
            app.Label_9.Text = 'θ2:';
            app.Spinner_10 = uispinner(app.UIFigure);
            app.Spinner_10.ValueChangedFcn = createCallbackFcn(app, @Spinner_10ValueChanged, true);
            app.Spinner_10.Enable = 'off';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Enable = 'off';
            app.Label_10.Text = 'θ3:';
            app.Spinner_11 = uispinner(app.UIFigure);
            app.Spinner_11.ValueChangedFcn = createCallbackFcn(app, @Spinner_11ValueChanged, true);
            app.Spinner_11.Enable = 'off';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = 'θ4:';
            app.Spinner_12 = uispinner(app.UIFigure);
            app.Spinner_12.ValueChangedFcn = createCallbackFcn(app, @Spinner_12ValueChanged, true);
            app.Spinner_12.Enable = 'off';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Enable = 'off';
            app.Label_12.Text = 'θ5:';
            app.Spinner_13 = uispinner(app.UIFigure);
            app.Spinner_13.ValueChangedFcn = createCallbackFcn(app, @Spinner_13ValueChanged, true);
            app.Spinner_13.Enable = 'off';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Enable = 'off';
            app.Label_13.Text = 'θ6:';
            app.Spinner_14 = uispinner(app.UIFigure);
            app.Spinner_14.ValueChangedFcn = createCallbackFcn(app, @Spinner_14ValueChanged, true);
            app.Spinner_14.Enable = 'off';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Enable = 'off';
            app.Label_14.Text = 'θ7:';
            app.Spinner_15 = uispinner(app.UIFigure);
            app.Spinner_15.ValueChangedFcn = createCallbackFcn(app, @Spinner_15ValueChanged, true);
            app.Spinner_15.Enable = 'off';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Enable = 'off';
            app.Label_15.Text = 'θ8:';
            app.Spinner_16 = uispinner(app.UIFigure);
            app.Spinner_16.ValueChangedFcn = createCallbackFcn(app, @Spinner_16ValueChanged, true);
            app.Spinner_16.Enable = 'off';
            app.L0SpinnerLabel = uilabel(app.UIFigure);
            app.L0SpinnerLabel.Enable = 'off';
            app.L0SpinnerLabel.Text = 'L0:';
            app.L0Spinner = uispinner(app.UIFigure);
            app.L0Spinner.Step = 0.05;
            app.L0Spinner.Limits = [0.05 0.5];
            app.L0Spinner.ValueChangedFcn = createCallbackFcn(app, @L0SpinnerValueChanged, true);
            app.L0Spinner.Enable = 'off';
            app.L0Spinner.Value = 0.3;
            app.InitialsolutionLabel = uilabel(app.UIFigure);
            app.InitialsolutionLabel.Text = 'Initial solution:';
            app.AdvancedparametersoffmincomLabel = uilabel(app.UIFigure);
            app.AdvancedparametersoffmincomLabel.Text = 'Advanced parameters of fmincom:';
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel = uilabel(app.UIFigure);
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'off';
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Text = 'To optimize the number of rotors, the optimization tool uses a genetic algorithm, therefore there is no need for an initial solution';
            app.Spinner_17 = uispinner(app.UIFigure);
            app.Spinner_17.Limits = [3 8];
            app.Spinner_17.ValueChangedFcn = createCallbackFcn(app, @Spinner_17ValueChanged, true);
            app.Spinner_17.Enable = 'off';
            app.Spinner_17.Value = 3;
            app.Spinner_18 = uispinner(app.UIFigure);
            app.Spinner_18.Limits = [4 10];
            app.Spinner_18.ValueChangedFcn = createCallbackFcn(app, @Spinner_18ValueChanged, true);
            app.Spinner_18.Enable = 'off';
            app.Spinner_18.Value = 8;
            app.MinimumnumberofrotorsLabel = uilabel(app.UIFigure);
            app.MinimumnumberofrotorsLabel.Enable = 'off';
            app.MinimumnumberofrotorsLabel.Text = 'Minimum number of rotors:';
            app.MaximumnumberofrotorsLabel = uilabel(app.UIFigure);
            app.MaximumnumberofrotorsLabel.Enable = 'off';
            app.MaximumnumberofrotorsLabel.Text = 'Maximum number of rotors:';
            app.AlgorithmListBoxLabel = uilabel(app.UIFigure);
            app.AlgorithmListBoxLabel.Text = 'Algorithm:';
            app.AlgorithmListBox = uilistbox(app.UIFigure);
            app.AlgorithmListBox.Items = {'sqp', 'sqp-legacy', 'interior-point', 'active-set'};
            app.AlgorithmListBox.ValueChangedFcn = createCallbackFcn(app, @AlgorithmListBoxValueChanged, true);
            app.AlgorithmListBox.Value = 'sqp';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display:';
            app.DisplayListBox = uilistbox(app.UIFigure);
            app.DisplayListBox.Items = {'displays no output', 'displays output at each iteration', 'displays output only if the function does not converge', 'displays only the final output,'};
            app.DisplayListBox.ItemsData = {'off', 'iter-detailed', 'notify-detailed', 'final-detailed', ''};
            app.DisplayListBox.ValueChangedFcn = createCallbackFcn(app, @DisplayListBoxValueChanged, true);
            app.DisplayListBox.Value = 'off';
            app.MaximumiterationsofthealgorithmLabel = uilabel(app.UIFigure);
            app.MaximumiterationsofthealgorithmLabel.Text = 'Maximum iterations of the algorithm:';
            app.MaximumiterationsofthealgorithmSpinner = uispinner(app.UIFigure);
            app.MaximumiterationsofthealgorithmSpinner.Limits = [1000 100000];
            app.MaximumiterationsofthealgorithmSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumiterationsofthealgorithmSpinnerValueChanged, true);
            app.MaximumiterationsofthealgorithmSpinner.Value = 10000;
            app.ToleranceontheconstraintviolationSpinnerLabel = uilabel(app.UIFigure);
            app.ToleranceontheconstraintviolationSpinnerLabel.Text = 'Tolerance on the constraint violation:';
            app.ToleranceontheconstraintviolationSpinner = uispinner(app.UIFigure);
            app.ToleranceontheconstraintviolationSpinner.Step = 1e-07;
            app.ToleranceontheconstraintviolationSpinner.Limits = [1e-09 0.0001];
            app.ToleranceontheconstraintviolationSpinner.ValueChangedFcn = createCallbackFcn(app, @ToleranceontheconstraintviolationSpinnerValueChanged, true);
            app.ToleranceontheconstraintviolationSpinner.Value = 1e-06;
            app.TerminationtoleranceontheoptargSpinnerLabel = uilabel(app.UIFigure);
            app.TerminationtoleranceontheoptargSpinnerLabel.Text = 'Termination tolerance on the opt. arg.:';
            app.TerminationtoleranceontheoptargSpinner = uispinner(app.UIFigure);
            app.TerminationtoleranceontheoptargSpinner.Step = 1e-07;
            app.TerminationtoleranceontheoptargSpinner.Limits = [1e-10 0.0001];
            app.TerminationtoleranceontheoptargSpinner.ValueChangedFcn = createCallbackFcn(app, @TerminationtoleranceontheoptargSpinnerValueChanged, true);
            app.TerminationtoleranceontheoptargSpinner.Value = 1e-06;
            app.ParametersoftheoptimizationfunctionLabel_2 = uilabel(app.UIFigure);
            app.ParametersoftheoptimizationfunctionLabel_2.Text = 'Parameters for the plot of the force and torque space:';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.OptimizationtooltofindanoptimaldronedesignLabel = uilabel(app.UIFigure);
            app.OptimizationtooltofindanoptimaldronedesignLabel.Text = 'Optimization tool to find an optimal drone design:';
            app.CostfunctionLabel = uilabel(app.UIFigure);
            app.CostfunctionLabel.Text = 'Cost function: ';
            app.CostfunctionListBox = uilistbox(app.UIFigure);
            app.CostfunctionListBox.Items = {'Maximize the minimal torque and force that the drone can apply', 'Maximize the minimal torque and force that the drone can apply and minimize the inertia of the drone', 'Maximize the force and torque in every direction and the hover efficiency in any orientation', 'Maximize the force and torque that the drone can apply in one direction (d)', 'Maximize the force and torque that the drone can apply in one direction (d), with a hover in any orientation condition', 'Maximize the torque and force that the drone can apply in x, y and z directions', 'Maximize the volume of the force space and the torque space'};
            app.CostfunctionListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.CostfunctionListBox.ValueChangedFcn = createCallbackFcn(app, @CostfunctionListBoxValueChanged, true);
            app.CostfunctionListBox.Value = '1';
            app.LminSpinnerLabel = uilabel(app.UIFigure);
            app.LminSpinnerLabel.Enable = 'off';
            app.LminSpinnerLabel.Text = 'Lmin:';
            app.LminSpinner = uispinner(app.UIFigure);
            app.LminSpinner.Step = 0.05;
            app.LminSpinner.Limits = [0.05 0.4];
            app.LminSpinner.ValueChangedFcn = createCallbackFcn(app, @LminSpinnerValueChanged, true);
            app.LminSpinner.Enable = 'off';
            app.LminSpinner.Value = 0.2;
            app.LmaxSpinnerLabel = uilabel(app.UIFigure);
            app.LmaxSpinnerLabel.Enable = 'off';
            app.LmaxSpinnerLabel.Text = 'Lmax:';
            app.LmaxSpinner = uispinner(app.UIFigure);
            app.LmaxSpinner.Step = 0.05;
            app.LmaxSpinner.Limits = [0.3 0.7];
            app.LmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @LmaxSpinnerValueChanged, true);
            app.LmaxSpinner.Enable = 'off';
            app.LmaxSpinner.Value = 0.4;
            app.minSpinnerLabel = uilabel(app.UIFigure);
            app.minSpinnerLabel.Text = 'βmin:';
            app.minSpinner = uispinner(app.UIFigure);
            app.minSpinner.Limits = [-180 0];
            app.minSpinner.ValueChangedFcn = createCallbackFcn(app, @minSpinnerValueChanged, true);
            app.minSpinner.Value = -90;
            app.maxSpinnerLabel = uilabel(app.UIFigure);
            app.maxSpinnerLabel.Text = 'βmax:';
            app.maxSpinner = uispinner(app.UIFigure);
            app.maxSpinner.Limits = [0 180];
            app.maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxSpinnerValueChanged, true);
            app.maxSpinner.Value = 90;
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Enable = 'off';
            app.minLabel.Text = 'θmin:';
            app.minSpinner_4 = uispinner(app.UIFigure);
            app.minSpinner_4.Limits = [-180 0];
            app.minSpinner_4.ValueChangedFcn = createCallbackFcn(app, @minSpinner_4ValueChanged, true);
            app.minSpinner_4.Enable = 'off';
            app.minSpinner_4.Value = -90;
            app.maxSpinner_4Label = uilabel(app.UIFigure);
            app.maxSpinner_4Label.Enable = 'off';
            app.maxSpinner_4Label.Text = 'θmax:';
            app.maxSpinner_4 = uispinner(app.UIFigure);
            app.maxSpinner_4.Limits = [0 180];
            app.maxSpinner_4.ValueChangedFcn = createCallbackFcn(app, @maxSpinner_4ValueChanged, true);
            app.maxSpinner_4.Enable = 'off';
            app.maxSpinner_4.Value = 90;
            app.ParametersboundsLabel = uilabel(app.UIFigure);
            app.ParametersboundsLabel.Text = 'Parameters bounds:';
            app.LSpinnerLabel = uilabel(app.UIFigure);
            app.LSpinnerLabel.Text = 'L:';
            app.LSpinner = uispinner(app.UIFigure);
            app.LSpinner.Step = 0.1;
            app.LSpinner.Limits = [0 1];
            app.LSpinner.ValueChangedFcn = createCallbackFcn(app, @LSpinnerValueChanged, true);
            app.LSpinner.Value = 0.3;
            app.DesignparametersLabel = uilabel(app.UIFigure);
            app.DesignparametersLabel.Text = 'Design parameters:';
            app.DirectiondLabel = uilabel(app.UIFigure);
            app.DirectiondLabel.Enable = 'off';
            app.DirectiondLabel.Text = 'Direction d:';
            app.xSpinnerLabel = uilabel(app.UIFigure);
            app.xSpinnerLabel.Enable = 'off';
            app.xSpinnerLabel.Text = 'x:';
            app.xSpinner = uispinner(app.UIFigure);
            app.xSpinner.Limits = [-1 1];
            app.xSpinner.ValueDisplayFormat = '%.0f';
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged, true);
            app.xSpinner.Enable = 'off';
            app.ySpinnerLabel = uilabel(app.UIFigure);
            app.ySpinnerLabel.Enable = 'off';
            app.ySpinnerLabel.Text = 'y:';
            app.ySpinner = uispinner(app.UIFigure);
            app.ySpinner.Limits = [-1 1];
            app.ySpinner.ValueDisplayFormat = '%.0f';
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.ySpinner.Enable = 'off';
            app.zSpinnerLabel = uilabel(app.UIFigure);
            app.zSpinnerLabel.Enable = 'off';
            app.zSpinnerLabel.Text = 'z:';
            app.zSpinner = uispinner(app.UIFigure);
            app.zSpinner.Limits = [-1 1];
            app.zSpinner.ValueDisplayFormat = '%.0f';
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.zSpinner.Enable = 'off';
            app.zSpinner.Value = 1;
            app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel_2 = uilabel(app.UIFigure);
            app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel_2.Text = 'Maximal number of times fmincom is iterated to find the optimal solution';
            app.MaximalnumberoftimesfmincomisiteratedSpinner_2 = uispinner(app.UIFigure);
            app.MaximalnumberoftimesfmincomisiteratedSpinner_2.Limits = [50 200];
            app.MaximalnumberoftimesfmincomisiteratedSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MaximalnumberoftimesfmincomisiteratedSpinner_2ValueChanged, true);
            app.MaximalnumberoftimesfmincomisiteratedSpinner_2.Value = 150;
            app.NumberofpointsofthetorqueandforcespaceDropDownLabel = uilabel(app.UIFigure);
            app.NumberofpointsofthetorqueandforcespaceDropDownLabel.Text = 'Number of points of the torque and force space:';
            app.NumberofpointsofthetorqueandforcespaceDropDown = uidropdown(app.UIFigure);
            app.NumberofpointsofthetorqueandforcespaceDropDown.Items = {'7490', '1154', '578', '98', '26'};
            app.NumberofpointsofthetorqueandforcespaceDropDown.ItemsData = {'0.1', '0.2', '0.25', '0.5', '1'};
            app.NumberofpointsofthetorqueandforcespaceDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofpointsofthetorqueandforcespaceDropDownValueChanged, true);
            app.NumberofpointsofthetorqueandforcespaceDropDown.Value = '0.1';
            app.PerformanoptimizationonthetiltinganglesandontheLabel = uilabel(app.UIFigure);
            app.PerformanoptimizationonthetiltinganglesandontheLabel.Text = 'Perform an optimization on the tilting angles and on the rotor speed for every points of the torque/force space (time consuming):';
            app.DesignnumberEditFieldLabel = uilabel(app.UIFigure);
            app.DesignnumberEditFieldLabel.Text = 'Design number:';
            app.DesignnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesignnumberEditField.ValueDisplayFormat = '%.0f';
            app.DesignnumberEditField.ValueChangedFcn = createCallbackFcn(app, @DesignnumberEditFieldValueChanged, true);
            app.DesignnumberEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mav_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end